classdef scara2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SCARA2P2PUIFigure               matlab.ui.Figure
        SettingsPanel                   matlab.ui.container.Panel
        TargetConfigurationButtonGroup  matlab.ui.container.ButtonGroup
        leftButton                      matlab.ui.control.RadioButton
        rightButton                     matlab.ui.control.RadioButton
        SolversButtonGroup              matlab.ui.container.ButtonGroup
        IpoptButton                     matlab.ui.control.RadioButton
        SQPOSQPButton                   matlab.ui.control.RadioButton
        sensolveCheckBox                matlab.ui.control.CheckBox
        sensolveLamp                    matlab.ui.control.Lamp
        nlpLamp                         matlab.ui.control.Lamp
        ResultsPanel                    matlab.ui.container.Panel
        q_UIAxes                        matlab.ui.control.UIAxes
        q_dot_UIAxes                    matlab.ui.control.UIAxes
        q_ddot_UIAxes                   matlab.ui.control.UIAxes
        q_dddot_UIAxes                  matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        robplot_UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        robpar % robot parameters
        robplot % robot plot handles
        nlp % trajectory planning NLP
        rob % robot state
        running % Description
    end
    
    methods (Access = private)
        
        function init_robpar(app)            
            app.robpar.l1 = 0.7;    
            app.robpar.l2 = 0.5;
            app.robpar.q_min = [-2*pi;-2*pi];
            app.robpar.q_max = [2*pi;2*pi];
            app.robpar.q_dot_min = -0.5*ones(2,1);
            app.robpar.q_dot_max = 0.5*ones(2,1);
            app.robpar.q_ddot_min = -5*ones(2,1);
            app.robpar.q_ddot_max = 5*ones(2,1);
        end
        
        function [I_r_P1, I_r_P2, varargout] = forward_kinematics(app, q, varargin)
            I_r_P1 = app.robpar.l1*[cos(q(1,:)); sin(q(1,:))];
            I_r_P2 = I_r_P1 + app.robpar.l2*[cos(sum(q,1)); sin(sum(q,1))];
            if nargin > 2 && nargout == 4
                q_dot = varargin{1};
                I_v_P1 = app.robpar.l1*[-sin(q(1,:)); cos(q(1,:))].*q_dot(1,:);
                I_v_P2 = I_v_P1 + app.robpar.l2*[-sin(sum(q,1)); cos(sum(q,1))].*(sum(q_dot,1));
                varargout{1} = I_v_P1;
                varargout{2} = I_v_P2;
            end
        end
        
        function init_robplot(app)
            rectangle(app.robplot_UIAxes, 'Position',(app.robpar.l1+app.robpar.l2)*[-1 -1 2 2],'Curvature',[1,1]);
            hold(app.robplot_UIAxes, 'all');
            q = app.rob.q;
            [I_r_P1, I_r_P2] = app.forward_kinematics(q);
            if ~isempty(app.robplot)
            app.robplot.XData = [0,I_r_P1(1),I_r_P2(1)];
            app.robplot.YData = [0,I_r_P1(2),I_r_P2(2)];
            else
                app.robplot = plot(app.robplot_UIAxes, [0,I_r_P1(1),I_r_P2(1)], [0,I_r_P1(2),I_r_P2(2)], 'k-o');
            end
            xlim(app.robplot_UIAxes, (app.robpar.l1+app.robpar.l2)*[-1.2,1.2]);
            ylim(app.robplot_UIAxes, (app.robpar.l1+app.robpar.l2)*[-1.2,1.2]);
        end
        
        function robplot_mouse(app, this_mouse)
            I_r_P2 = this_mouse(1,1:2)';
            if app.leftButton.Value
                ik_conf = 'l';
            else
                assert(app.rightButton.Value);
                ik_conf = 'r';
            end
            q = invkin(app, I_r_P2, ik_conf);
            [I_r_P1, I_r_P2] = app.forward_kinematics(q);
            app.robplot.XData = [0,I_r_P1(1),I_r_P2(1)];
            app.robplot.YData = [0,I_r_P1(2),I_r_P2(2)];
            [sensolve_sol, ipopt_sol, sqp_sol] = app.solve_P2P(app.rob.q, q, app.nlp.w_nom, app.nlp.p_nom, app.nlp.lam_g_nom);
            app.rob.q = q;
            if sensolve_sol.stats.success
                app.sensolveLamp.Color = [0,1,0];
            else
                app.sensolveLamp.Color = [1,0,0];
            end
            if ~isempty(ipopt_sol) && ipopt_sol.stats.success || ~isempty(sqp_sol) && sqp_sol.stats.success
                app.nlpLamp.Color = [0,1,0];
            else
                app.nlpLamp.Color = [1,0,0];
            end
            if sensolve_sol.stats.success
                plot_trajectory(app, sensolve_sol.w);
                p_pert = [app.rob.q; zeros(4,1); q; zeros(4,1)];
                f_sensolve = full(app.nlp.sensolve.nlp_f(sensolve_sol.w, p_pert));
                f_compare = 0;
                t_sensolve = sensolve_sol.stats.t_wall_total;
                t_compare = 0;
                if ~isempty(ipopt_sol)
                    f_compare = full(app.nlp.sensolve.nlp_f(ipopt_sol.w, p_pert));
                    t_compare = ipopt_sol.stats.t_wall_total;
                else
                    assert(~isempty(sqp_sol));
                    f_compare = full(app.nlp.sensolve.nlp_f(sqp_sol.w, p_pert));
                    t_compare = sqp_sol.stats.t_proc_BFGS + sqp_sol.stats.t_proc_QP + sqp_sol.stats.t_proc_callback_fun + sqp_sol.stats.t_proc_convexify + sqp_sol.stats.t_proc_linesearch + sqp_sol.stats.t_proc_nlp_fg + sqp_sol.stats.t_proc_nlp_grad + sqp_sol.stats.t_proc_nlp_hess_l + sqp_sol.stats.t_proc_nlp_jac_fg + sqp_sol.stats.t_wall_BFGS + sqp_sol.stats.t_wall_QP + sqp_sol.stats.t_wall_callback_fun + sqp_sol.stats.t_wall_convexify + sqp_sol.stats.t_wall_linesearch + sqp_sol.stats.t_wall_nlp_fg + sqp_sol.stats.t_wall_nlp_grad + sqp_sol.stats.t_wall_nlp_hess_l + sqp_sol.stats.t_wall_nlp_jac_fg;
                end
                %fprintf('%f\n',t_compare);
                rel_cost = (f_sensolve/f_compare-1)*100;
                if rel_cost > 0
                    rel_cost_sign = '+';
                else
                    rel_cost_sign = '';
                end
                app.ResultsPanel.Title = sprintf('Results (sensolve/NLP): cost %s%0.2f%%, time %0.2f%%', rel_cost_sign, rel_cost, (t_sensolve/t_compare-1)*100);
            else
                app.ResultsPanel.Title = 'Results: Error';
            end
        end
        
        function q = invkin(app, I_r_P2, conf)
            x = I_r_P2(1);
            y = I_r_P2(2);
            rr = norm(I_r_P2);
            alpha = atan2(y, x);
            beta = acos((app.robpar.l1^2 + rr^2 - app.robpar.l2^2)/(2*app.robpar.l1*rr));
            switch conf
                case 'l'
                    q1 = alpha + beta;
                case 'r'
                    q1 = alpha - beta;
                otherwise
                    q1 = alpha + beta;
            end
            q1 = wrapToPi(q1);
            x1 = app.robpar.l1*cos(q1);
            y1 = app.robpar.l1*sin(q1);
            q2abs = atan2(y - y1, x - x1);
            q2 = wrapToPi(q2abs - q1);
            
            q = [q1; q2];
        end
        
        function init_solvers(app, N, T)
            q_dim = 2;
            Ts = T/N;
            x_dim = 3*q_dim;
            u_dim = q_dim;
            x_idx_q = 1:q_dim;
            x_idx_q_dot = (1:q_dim) + x_idx_q(end);
            x_idx_q_ddot = (1:q_dim) + x_idx_q_dot(end);
            
            
            % cost function
            x = casadi.SX.sym('x', x_dim);
            u = casadi.SX.sym('u', u_dim);
            cost = sumsqr(u);
            cost_fun = casadi.Function('cost_fun', {x,u}, {cost});
            
            % state derivative
            x = casadi.SX.sym('x', x_dim);
            u = casadi.SX.sym('u', u_dim);
            x_dot = [x(q_dim+1:end)
                     u];
            x_dot_fun = casadi.Function('x_dot_fun', {x,u}, {x_dot});
            
            % integrator
            x = casadi.SX.sym('x', x_dim);
            p = casadi.SX.sym('p', u_dim+1);
            u = p(1:u_dim);
            Ts_ = p(end);
            ode = struct('x', x, 'p', p, 'ode', Ts_*x_dot_fun(x, u), 'quad', Ts_*cost_fun(x,u));
            integ = casadi.integrator('integ', 'rk', ode, struct('number_of_finite_elements', 4, 'simplify', true));
            
            x_mat = casadi.MX.sym('x_mat', [x_dim, N+1]);
            q_mat = x_mat(x_idx_q,:);
            q_dot_mat = x_mat(x_idx_q_dot,:);
            q_ddot_mat = x_mat(x_idx_q_ddot,:);
            u_mat = casadi.MX.sym('u_mat', [u_dim, N]);
            q_dddot_mat = u_mat;
            
            integ_map = integ.map(N);
            p_mat = [u_mat; Ts*ones(1,N)];
            res = integ_map('x0', x_mat(:,1:end-1), 'p', p_mat);
            cost = sum(res.qf);
            % shooting gaps
            shooting_gap = res.xf - x_mat(:,2:end);
            
            %% constraints
            % parameters
            q_init = casadi.MX.sym('q_init', q_dim);
            q_dot_init = casadi.MX.sym('q_dot_init', q_dim);
            q_ddot_init = casadi.MX.sym('q_ddot_init', q_dim);
            q_term = casadi.MX.sym('q_term', q_dim);
            q_dot_term = casadi.MX.sym('q_dot_term', q_dim);
            q_ddot_term = casadi.MX.sym('q_ddot_term', q_dim);
            p = [q_init; q_dot_init; q_ddot_init; q_term; q_dot_term; q_ddot_term];
            % equality constraints
            g_eq = [shooting_gap(:);
                    flatten([q_init,q_term] - q_mat(:,[1,end]));
                    flatten([q_dot_init,q_dot_term] - q_dot_mat(:,[1,end]));
                    flatten([q_ddot_init,q_ddot_term] - q_ddot_mat(:,[1,end]))];
            g_eq_lb = zeros(size(g_eq));
            g_eq_ub = g_eq_lb;
            
            % inequality constraints
            g_ineq = [];
            g_ineq_lb = [];
            g_ineq_ub = [];
            g_ineq = [g_ineq;
                    flatten(q_mat(:,2:end-1));
                    flatten(q_dot_mat(:,2:end-1));
                flatten(q_ddot_mat(:,2:end-1))];
            g_ineq_lb = [g_ineq_lb;
                        repmat(app.robpar.q_min, [N-1,1]);
                        repmat(app.robpar.q_dot_min, [N-1,1]);
                    repmat(app.robpar.q_ddot_min, [N-1,1])];
            g_ineq_ub = [g_ineq_ub;
                        repmat(app.robpar.q_max, [N-1,1]);
                        repmat(app.robpar.q_dot_max, [N-1,1]);
                    repmat(app.robpar.q_ddot_max, [N-1,1])];
            g_ineq = [g_ineq_lb(~isinf(g_ineq_lb)) - g_ineq(idxvec(~isinf(g_ineq_lb)));
                      g_ineq(idxvec(~isinf(g_ineq_ub))) - g_ineq_ub(~isinf(g_ineq_ub))];
            g_ineq_lb = -inf(size(g_ineq));
            g_ineq_ub = zeros(size(g_ineq));
            
            % collect constraints
            g = [g_eq; g_ineq];
            app.nlp.g_lb = [g_eq_lb; g_ineq_lb];
            app.nlp.g_ub = [g_eq_ub; g_ineq_ub];
            
            % mask equality constraints
            app.nlp.g_mask_eq = [true(size(g_eq)); false(size(g_ineq))];
            
            % optimization variables
            nlp_fun = casadi.Function('nlp_fun', {x_mat, u_mat, p}, {cost, g});
            w = casadi.MX.sym('w', (N+1)*x_dim + N*u_dim);
            tmp_mat = reshape(w(1:end-x_dim), x_dim+u_dim, N);
            x_mat = [tmp_mat(1:x_dim,:), w(end-x_dim+1:end)];
            u_mat = tmp_mat(x_dim+1:end,:);
            [cost, g] = nlp_fun(x_mat, u_mat, p);
            
            nlp_ = struct('x', w, 'f', cost, 'g', g, 'p', p);
            app.nlp.sqp = casadi.nlpsol('solver', 'sqpmethod', nlp_, struct('qpsol', 'osqp', 'max_iter', 100));%, 'print_header', false, 'print_status', false, 'print_time', false, 'print_iteration', false));
            app.nlp.ipopt = casadi.nlpsol('solver', 'ipopt', nlp_, struct('ipopt', struct('print_level', 0)));
            app.nlp.sensolve = sensolve(app.nlp.sqp);
        end
        
        function [sensolve_sol, ipopt_sol, sqp_sol] = solve_P2P(app, q_start, q_end, w_nom, p_nom, lam_g_nom)
            p_pert = [q_start; zeros(4,1); q_end; zeros(4,1)];      
            [w, lam_g, g_mask_act, stats] = app.nlp.sensolve.solve(w_nom, p_nom, p_pert, lam_g_nom, app.nlp.g_mask_eq);
            sensolve_sol.solver_name = 'sensolve';
            sensolve_sol.w = w;
            sensolve_sol.lam_g = lam_g;
            sensolve_sol.g_mask_act = g_mask_act;
            sensolve_sol.stats = stats;
            ipopt_sol = [];
            if app.IpoptButton.Value
                sol = app.nlp.ipopt('x0', w_nom, 'lam_g0', lam_g_nom, 'lbg', app.nlp.g_lb, 'ubg', app.nlp.g_ub, 'p', p_pert);
                ipopt_sol.solver_name = 'Ipopt';
                ipopt_sol.w = full(sol.x);
                ipopt_sol.lam_g = full(sol.lam_g);
                ipopt_sol.stats = app.nlp.ipopt.stats;
            end
            sqp_sol = [];
            if app.SQPOSQPButton.Value
                sol = app.nlp.sqp('x0', w_nom, 'lam_g0', lam_g_nom, 'lbg', app.nlp.g_lb, 'ubg', app.nlp.g_ub, 'p', p_pert);
                sqp_sol.solver_name = 'SQP';
                sqp_sol.w = full(sol.x);
                sqp_sol.lam_g = full(sol.lam_g);
                sqp_sol.stats = app.nlp.sqp.stats;
            end            
        end
        
        function plot_trajectory(app, w_opt)
            [~, ~, q_mat, q_dot_mat, q_ddot_mat, q_dddot_mat] = app.w2traj(w_opt);
            [~, I_r_P2_mat, ~, I_v_P2_mat] = app.forward_kinematics(q_mat, q_dot_mat);
            
            % 2D plots
            plot(app.robplot_UIAxes, I_r_P2_mat(1,:), I_r_P2_mat(2,:));
            %quiver(app.robplot_UIAxes, I_r_P2_mat(1,:), I_r_P2_mat(2,:), I_v_P2_mat(1,:), I_v_P2_mat(2,:))
            app.animate_trajectory(w_opt);
            
            % time plots
            t_vec = linspace(0, app.nlp.T, app.nlp.N+1);
            plot(app.q_UIAxes, t_vec, q_mat');
            plot(app.q_dot_UIAxes, t_vec, q_dot_mat');
            plot(app.q_ddot_UIAxes, t_vec, q_ddot_mat');
            stairs(app.q_dddot_UIAxes, t_vec, [q_dddot_mat,q_dddot_mat(:,end)]'); 
            
        end
        
        function [x_mat, u_mat, q_mat, q_dot_mat, q_ddot_mat, q_dddot_mat] = w2traj(app, w_opt)
            q_dim = 2;
            x_dim = 3*q_dim;
            u_dim = q_dim;
            x_idx.q = 1:q_dim;
            x_idx.q_dot = (1:q_dim) + x_idx.q(end);
            x_idx.q_ddot = (1:q_dim) + x_idx.q_dot(end);
            tmp_mat = reshape(w_opt(1:end-x_dim), x_dim+u_dim, app.nlp.N);
            x_mat = [tmp_mat(1:x_dim,:), w_opt(end-x_dim+1:end)];
            u_mat = tmp_mat(x_dim+1:end,:);
            
            q_mat = x_mat(x_idx.q,:);
            q_dot_mat = x_mat(x_idx.q_dot,:);
            q_ddot_mat = x_mat(x_idx.q_ddot,:);
            q_dddot_mat = u_mat;            
        end        
        
        function animate_trajectory(app, w_opt)
            [~, ~, q_mat] = app.w2traj(w_opt);
            [I_r_P1_mat, I_r_P2_mat] = app.forward_kinematics(q_mat);
            dt = app.nlp.T/app.nlp.N;
            this_robplot = plot(app.robplot_UIAxes, [0,I_r_P1_mat(1,1),I_r_P2_mat(1,1)], [0,I_r_P1_mat(2,1),I_r_P2_mat(2,1)], 'b-o');
            for idx_frame = 1:app.nlp.N-1
                tic
                this_robplot.XData = [0,I_r_P1_mat(1,idx_frame),I_r_P2_mat(1,idx_frame)];
                this_robplot.YData = [0,I_r_P1_mat(2,idx_frame),I_r_P2_mat(2,idx_frame)];
                drawnow();
                t_wait = dt - toc;
                pause(t_wait);
            end
            delete(this_robplot)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, w_nom, p_nom, lam_g_nom)
            try
                casadi.SX.sym('test');
            catch
                warning('Please add CasADi to your MATLAB path');
                delete(app)
                return
            end
            
            app.running = true;
            app.nlp.N = 100;
            app.nlp.T = 5;
            app.init_robpar();
            [~,~,q_mat] = app.w2traj(w_nom);
            app.nlp.w_nom = w_nom;
            app.nlp.p_nom = p_nom;
            app.nlp.lam_g_nom = lam_g_nom;
            app.rob.q = q_mat(:,1);
            if app.rob.q(2) > 0
                app.rightButton.Value = 1;
            else
                app.leftButton.Value = 1;
            end
            % show nominal trajectory
            [~, I_r_P2_mat] = app.forward_kinematics(q_mat);   
            plot(app.robplot_UIAxes, I_r_P2_mat(1,:), I_r_P2_mat(2,:),'r');
            
            app.init_robplot();
            app.init_solvers(app.nlp.N, app.nlp.T);
            last_mouse = app.robplot_UIAxes.CurrentPoint;
            while app.running
                this_mouse = app.robplot_UIAxes.CurrentPoint;
                if any(this_mouse(:) ~= last_mouse(:)) && norm(this_mouse(1,1:2)) <= (app.robpar.l1+app.robpar.l2) && norm(this_mouse(1,1:2)) >= (app.robpar.l1-app.robpar.l2)
                    app.robplot_mouse(this_mouse);
                    last_mouse = this_mouse;
                end
                pause(0.01);
            end
            delete(app)
        end

        % Close request function: SCARA2P2PUIFigure
        function SCARA2P2PUIFigureCloseRequest(app, event)
            app.running = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SCARA2P2PUIFigure = uifigure('Visible', 'off');
            app.SCARA2P2PUIFigure.Name = 'SCARA2 P2P';
            app.SCARA2P2PUIFigure.CloseRequestFcn = createCallbackFcn(app, @SCARA2P2PUIFigureCloseRequest, true);
            app.SettingsPanel = uipanel(app.SCARA2P2PUIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.TargetConfigurationButtonGroup = uibuttongroup(app.SettingsPanel);
            app.TargetConfigurationButtonGroup.Title = 'Target Configuration';
            app.leftButton = uiradiobutton(app.TargetConfigurationButtonGroup);
            app.leftButton.Text = 'left';
            app.leftButton.Value = true;
            app.rightButton = uiradiobutton(app.TargetConfigurationButtonGroup);
            app.rightButton.Text = 'right';
            app.SolversButtonGroup = uibuttongroup(app.SettingsPanel);
            app.SolversButtonGroup.Title = 'Solvers';
            app.IpoptButton = uiradiobutton(app.SolversButtonGroup);
            app.IpoptButton.Text = 'Ipopt';
            app.IpoptButton.Value = true;
            app.SQPOSQPButton = uiradiobutton(app.SolversButtonGroup);
            app.SQPOSQPButton.Text = 'SQP (OSQP)';
            app.sensolveCheckBox = uicheckbox(app.SolversButtonGroup);
            app.sensolveCheckBox.Enable = 'off';
            app.sensolveCheckBox.Text = 'sensolve';
            app.sensolveCheckBox.Value = true;
            app.sensolveLamp = uilamp(app.SolversButtonGroup);
            app.nlpLamp = uilamp(app.SolversButtonGroup);
            app.ResultsPanel = uipanel(app.SCARA2P2PUIFigure);
            app.ResultsPanel.Title = 'Results';
            app.q_UIAxes = uiaxes(app.ResultsPanel);
            title(app.q_UIAxes, {''; ''})
            xlabel(app.q_UIAxes, '')
            ylabel(app.q_UIAxes, 'q')
            app.q_dot_UIAxes = uiaxes(app.ResultsPanel);
            title(app.q_dot_UIAxes, {''; ''})
            xlabel(app.q_dot_UIAxes, '')
            ylabel(app.q_dot_UIAxes, 'qdot')
            app.q_ddot_UIAxes = uiaxes(app.ResultsPanel);
            title(app.q_ddot_UIAxes, {''; ''})
            xlabel(app.q_ddot_UIAxes, '')
            ylabel(app.q_ddot_UIAxes, 'qddot')
            app.q_dddot_UIAxes = uiaxes(app.ResultsPanel);
            title(app.q_dddot_UIAxes, {''; ''})
            xlabel(app.q_dddot_UIAxes, 'time')
            ylabel(app.q_dddot_UIAxes, 'qdddot')
            app.Panel = uipanel(app.SCARA2P2PUIFigure);
            app.robplot_UIAxes = uiaxes(app.Panel);
            title(app.robplot_UIAxes, 'SCARA2')
            xlabel(app.robplot_UIAxes, 'x')
            ylabel(app.robplot_UIAxes, 'y')
            % Show the figure after all components are created
            app.SCARA2P2PUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scara2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SCARA2P2PUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SCARA2P2PUIFigure)
        end
    end
end